classdef imgediter < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure            matlab.ui.Figure
        panel1              matlab.ui.container.Panel
        output1Img          matlab.ui.control.Image
        GambarInputLabel_2  matlab.ui.control.Label
        GambarInputLabel    matlab.ui.control.Label
        inputImg            matlab.ui.control.Image
        prosesButton        matlab.ui.control.Button
        browseButton        matlab.ui.control.Button
        inputimgfield       matlab.ui.control.EditField
        UIAxes3             matlab.ui.control.UIAxes
        UIAxes2             matlab.ui.control.UIAxes
        UIAxes1             matlab.ui.control.UIAxes
        initPanel           matlab.ui.container.Panel
        soalMenu            matlab.ui.container.ButtonGroup
        SoalLabel           matlab.ui.control.Label
        soal4button         matlab.ui.control.RadioButton
        soal3button         matlab.ui.control.RadioButton
        soal2button         matlab.ui.control.RadioButton
        soal1button         matlab.ui.control.RadioButton
        subSoal2Menu        matlab.ui.container.ButtonGroup
        SubSoal2Label       matlab.ui.control.Label
        soal2Dbutton        matlab.ui.control.RadioButton
        soal2Cbutton        matlab.ui.control.RadioButton
        soal2Bbutton        matlab.ui.control.RadioButton
        soal2Abutton        matlab.ui.control.RadioButton
        ContextMenu         matlab.ui.container.ContextMenu
        Menu                matlab.ui.container.Menu
        Menu2               matlab.ui.container.Menu
    end

    
    methods (Access = private)
        
        % custom_image_histogram - fungsi untuk mengubah matriks gambar menjadi histogram
        function counts = custom_image_histogram(img)
            % buat semua ke double
            img = double(img);

            % ubah ke [0,255] dari range [0,1]
            if max(img(:)) <= 1
                img = img * 255;
            end

            % clamp semua nilai agar pasti dalam rentang [0,255]
            img(img < 0) = 0;
            img(img > 255) = 255;

            % bulatkan nilai ke integer terdekat
            img = round(img);

            counts = zeros(256,1);

            % hitung histogram manual
            [rows, cols] = size(img);
            for r = 1:rows
                for c = 1:cols
                    value = img(r,c);
                    counts(value+1) = counts(value+1) + 1;
                end
            end
        end

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Selection changed function: soalMenu
        function soalMenuSelected(app, event)
            selectedButton = app.soalMenu.SelectedObject;
            if selectedButton == app.soal1button
                %app.subSoal1Menu.Visible = 'on';
                app.subSoal2Menu.Visible = 'off';
                %app.subSoal3Menu.Visible = 'off';
                %app.subSoal4Menu.Visible = 'off';
                app.panel1.Visible ='on';
            end
            if selectedButton == app.soal2button
                %app.subSoal1Menu.Visible = 'off';
                app.panel1.Visible = 'off';
                app.subSoal2Menu.Visible = 'on';
                %app.subSoal3Menu.Visible = 'off';
                %app.subSoal4Menu.Visible = 'off';
            end
            if selectedButton == app.soal3button
                %app.subSoal1Menu.Visible = 'off';
                app.panel1.Visible = 'off';
                app.subSoal2Menu.Visible = 'off';
                %app.subSoal3Menu.Visible = 'on';
                %app.subSoal4Menu.Visible = 'off';
            end
            if selectedButton == app.soal4button
                %app.subSoal1Menu.Visible = 'off';
                app.panel1.Visible = 'off';
                app.subSoal2Menu.Visible = 'off';
                %app.subSoal3Menu.Visible = 'off';
                %app.subSoal4Menu.Visible = 'on';
            end
        end

        % Button pushed function: browseButton
        function browseButtonPushed(app, event)
             %currentPath = pwd;
             %winopen(pwd);
             [file, path] = uigetfile({'*.jpg;*.png;*.jpeg','Image Files (*.jpg,*.png,*.jpeg)'}, ...
                              'Pilih Gambar');
            if isequal(file,0)
                disp('User batal memilih file');
            else
                % Gabungkan path + nama file
                %fullPath = fullfile(path, file);
                imgname = file;
                % Masukkan ke edit field
                app.inputimgfield.Value = imgname;
                app.inputImg.Visible = 'on';
                app.inputImg.ImageSource = fullfile(path, file);
                app.prosesButton.Visible='on';
            end
        end

        % Value changed function: inputimgfield
        function inputimgfieldValueChanged(app, event)
                value = app.inputimgfield.Value;  % ambil path dari edit field

                if isfile(value)
                    img = imread(value);
                    imshow(img,'Parent',app.UIAxes1); % tampilkan di UIAxes
                else
                    uialert(app.UIFigure,'File tidak ditemukan','Error');
                end
            
        end

        % Button pushed function: prosesButton
        function prosesButtonPushed(app, event)
                % 1. Ambil gambar
            imageData = app.inputImg.ImageSource;
            img = imread(imageData);
        
            if app.soal1button.Value
                app.output1Img.ImageSource = img;
                app.output1Img.Visible = 'on';
                app.GambarInputLabel_2.Text = 'Citra Asli';

                % cek apakah gambar grayscale atau berwarna

                if size(img, 3) == 1
                    disp('Citra adalah gambar grayscale.');
    
                    % hitung histogram grayscale (hanya sekali)
                    counts = custom_image_histogram(img);
                    bins = 0:255;

                    %tampilkan histogram citra grayscale
                    figure;
                    bar(bins, counts, 'k');
                    title('Histogram Citra Grayscale (fungsi histogram dibuat sendiri)');
                    xlabel('Intensitas (0-255)');
                    ylabel('Jumlah Piksel');

                    cla(app.UIAxes1);
                    bar(app.UIAxes1, bins, counts, 'k');
                    title(app.UIAxes1,'Histogram Citra Grayscale (fungsi histogram dibuat sendiri)');
                    xlabel(app.UIAxes1,'Intensitas (0-255)');
                    ylabel(app.UIAxes1,'Jumlah Piksel');

                    cla(app.UIAxes2);
                    cla(app.UIAxes3);
                else
                    disp('Citra adalah gambar berwarna.');
                    %pisah matriks gambar RGB yang terdiri atas 3 channel menjadi matriks R, G, dan B
                    R = img(:,:,1);
                    G = img(:,:,2);
                    B = img(:,:,3);

                    % Hitung histogram tiap channel R,G,B (3 kali)
                    binsR = 0:255;
                    binsG = 0:255;
                    binsB = 0:255;
                    countR = custom_image_histogram(R);
                    countG = custom_image_histogram(G);
                    countB = custom_image_histogram(B);
    
                    % Tampilkan histogram RGB
                    figure;
                    bar(binsR, countR, 'r');
                    title('Histogram Citra R/Merah (fungsi histogram dibuat sendiri)');
                    xlabel('Intensitas (0-255)');
                    ylabel('Jumlah Piksel');

                    cla(app.UIAxes1);
                    bar(app.UIAxes1, binsR, countR, 'r');
                    title(app.UIAxes1, 'Histogram Citra R/Merah (fungsi histogram dibuat sendiri)');
                    xlabel(app.UIAxes1, 'Intensitas (0-255)');
                    ylabel(app.UIAxes1,'Jumlah Piksel');
                    figure;
                    bar(binsG, countG, 'g');
                    title('Histogram Citra G/Hijau (fungsi histogram dibuat sendiri)');
                    xlabel('Intensitas (0-255)');
                    ylabel('Jumlah Piksel');

                    cla(app.UIAxes2);
                    bar(app.UIAxes2,binsG, countG, 'g');
                    title(app.UIAxes2,'Histogram Citra G/Hijau (fungsi histogram dibuat sendiri)');
                    xlabel(app.UIAxes2,'Intensitas (0-255)');
                    ylabel(app.UIAxes2,'Jumlah Piksel');
                    figure;
                    bar(binsB, countB, 'b');
                    title('Histogram Citra B/Biru (fungsi histogram dibuat sendiri)');
                    xlabel('Intensitas (0-255)');
                    ylabel('Jumlah Piksel');

                    cla(app.UIAxes3);
                    bar(app.UIAxes3,binsB, countB, 'b');
                    title(app.UIAxes3,'Histogram Citra B/Biru (fungsi histogram dibuat sendiri)');
                    xlabel(app.UIAxes3,'Intensitas (0-255)');
                    ylabel(app.UIAxes3,'Jumlah Piksel');
                end
            else  
                % 2. Lakukan 50 baris kode pemrosesan gambar yang rumit
                %    misalnya konversi grayscale, deteksi tepi, filtering, dll.
                grayImg = rgb2gray(img);
                edgeImg = edge(grayImg, 'sobel');
           
                finalImage = edgeImg;
        
                % 3. Tampilkan hasil
                app.output1Img.ImageSource = grayImg;
                app.output1Img.Visible = 'on';
                app.output2Img.ImageSource = finalImage;
                app.output2Img.Visible = 'on';
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.initPanel = uipanel(app.UIFigure);
            app.initPanel.Title = 'Pengcit Editer';
            app.subSoal2Menu = uibuttongroup(app.initPanel);
            app.subSoal2Menu.Visible = 'off';
            app.soal2Abutton = uiradiobutton(app.subSoal2Menu);
            app.soal2Abutton.Text = 'Soal a';
            app.soal2Abutton.Value = true;
            app.soal2Bbutton = uiradiobutton(app.subSoal2Menu);
            app.soal2Bbutton.Text = 'Soal b';
            app.soal2Cbutton = uiradiobutton(app.subSoal2Menu);
            app.soal2Cbutton.Text = 'Soal c';
            app.soal2Dbutton = uiradiobutton(app.subSoal2Menu);
            app.soal2Dbutton.Text = 'Soal d';
            app.SubSoal2Label = uilabel(app.subSoal2Menu);
            app.SubSoal2Label.Text = 'SubSoal 2';
            app.soalMenu = uibuttongroup(app.initPanel);
            app.soalMenu.SelectionChangedFcn = createCallbackFcn(app, @soalMenuSelected, true);
            app.soal1button = uiradiobutton(app.soalMenu);
            app.soal1button.Text = 'Soal 1';
            app.soal1button.Value = true;
            app.soal2button = uiradiobutton(app.soalMenu);
            app.soal2button.Text = 'Soal 2';
            app.soal3button = uiradiobutton(app.soalMenu);
            app.soal3button.Text = 'Soal 3';
            app.soal4button = uiradiobutton(app.soalMenu);
            app.soal4button.Text = 'Soal 4';
            app.SoalLabel = uilabel(app.soalMenu);
            app.SoalLabel.Text = 'Soal : ';
            app.panel1 = uipanel(app.UIFigure);
            app.UIAxes1 = uiaxes(app.panel1);
            title(app.UIAxes1, 'Title')
            xlabel(app.UIAxes1, 'X')
            ylabel(app.UIAxes1, 'Y')
            zlabel(app.UIAxes1, 'Z')
            app.UIAxes2 = uiaxes(app.panel1);
            title(app.UIAxes2, 'Title')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes3 = uiaxes(app.panel1);
            title(app.UIAxes3, 'Title')
            xlabel(app.UIAxes3, 'X')
            ylabel(app.UIAxes3, 'Y')
            zlabel(app.UIAxes3, 'Z')
            app.inputimgfield = uieditfield(app.panel1, 'text');
            app.inputimgfield.ValueChangedFcn = createCallbackFcn(app, @inputimgfieldValueChanged, true);
            app.inputimgfield.Value = 'Gambar Input';
            app.browseButton = uibutton(app.panel1, 'push');
            app.browseButton.ButtonPushedFcn = createCallbackFcn(app, @browseButtonPushed, true);
            app.browseButton.Text = 'browse';
            app.prosesButton = uibutton(app.panel1, 'push');
            app.prosesButton.ButtonPushedFcn = createCallbackFcn(app, @prosesButtonPushed, true);
            app.prosesButton.Visible = 'off';
            app.prosesButton.Text = 'proses';
            app.inputImg = uiimage(app.panel1);
            app.inputImg.Visible = 'off';
            app.GambarInputLabel = uilabel(app.panel1);
            app.GambarInputLabel.Text = 'Gambar Input';
            app.GambarInputLabel_2 = uilabel(app.panel1);
            app.GambarInputLabel_2.Text = 'Gambar X';
            app.output1Img = uiimage(app.panel1);
            app.output1Img.Visible = 'off';
            app.ContextMenu = uicontextmenu(app.UIFigure);
            app.Menu = uimenu(app.ContextMenu);
            app.Menu.Text = 'Menu';
            app.Menu2 = uimenu(app.ContextMenu);
            app.Menu2.Text = 'Menu2';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = imgediter

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end